classdef GUIClassificatore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ChoosefolderButton  matlab.ui.control.Button
        Type1Button         matlab.ui.control.Button
        Type2Button         matlab.ui.control.Button
        Type3Button         matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        SaveButton          matlab.ui.control.Button
        SkipButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        images % Description
        currentImage % Description
        isOpen = false% Description
        classifier % Description
    end
    
    methods (Access = private)
        
        function nextImage(app)
            nOfImages = length(app.images);
            if nOfImages == 0
                return
            elseif app.currentImage < nOfImages
                app.currentImage=app.currentImage+1;
            else
                % avvisa con alert
                msgbox('End of images');
                app.currentImage = 1;
            end
            image = app.images(app.currentImage);
            imageObject = imread([image.folder '/' image.name]);
            imshow(imageObject,'parent',app.UIAxes);
            app.isOpen = true;
            
        end
        
        function assign(app,type)
            if app.isOpen == true
                name = app.images(app.currentImage).name;
                app.classifier(end+1,:) = {name, type};
                app.nextImage();
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            folder = uigetdir('','Select folder with images');
            if folder == 0
                return
            end
            app.images = dir([folder,'/*.jpg']);
            app.currentImage = 0;
            app.isOpen = false;
            app.classifier = cell(0,2);
            app.nextImage();

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            classifier = app.classifier;
            save('data/classifier.mat','classifier');
        end

        % Button pushed function: Type1Button
        function Type1ButtonPushed(app, event)
            app.assign(1);
        end

        % Button pushed function: Type2Button
        function Type2ButtonPushed(app, event)
            app.assign(2);
        end

        % Button pushed function: Type3Button
        function Type3ButtonPushed(app, event)
            app.assign(3);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            if app.isOpen == true
                app.nextImage();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.Type1Button = uibutton(app.UIFigure, 'push');
            app.Type1Button.ButtonPushedFcn = createCallbackFcn(app, @Type1ButtonPushed, true);
            app.Type1Button.Text = 'Type 1';
            app.Type2Button = uibutton(app.UIFigure, 'push');
            app.Type2Button.ButtonPushedFcn = createCallbackFcn(app, @Type2ButtonPushed, true);
            app.Type2Button.Text = 'Type 2';
            app.Type3Button = uibutton(app.UIFigure, 'push');
            app.Type3Button.ButtonPushedFcn = createCallbackFcn(app, @Type3ButtonPushed, true);
            app.Type3Button.Text = 'Type 3';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIClassificatore

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end